classdef challengeTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PositionFilterDropDown         matlab.ui.control.DropDown
        PositionFilterDropDownLabel    matlab.ui.control.Label
        ChallengeDropDown              matlab.ui.control.DropDown
        ChallengeDropDownLabel         matlab.ui.control.Label
        ChampionNameDropDown           matlab.ui.control.DropDown
        ChampionDropDownLabel          matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        Button                         matlab.ui.control.Button
        MarksmanSpecialistCheckBox     matlab.ui.control.CheckBox
        MasterMarksmanCheckBox         matlab.ui.control.CheckBox
        SupportSpecialistCheckBox      matlab.ui.control.CheckBox
        TankSpecialistCheckBox         matlab.ui.control.CheckBox
        AssassinSpecialistCheckBox     matlab.ui.control.CheckBox
        MageSpecialistCheckBox         matlab.ui.control.CheckBox
        FighterSpecialistCheckBox      matlab.ui.control.CheckBox
        MasterSupportCheckBox          matlab.ui.control.CheckBox
        MasterTankCheckBox             matlab.ui.control.CheckBox
        MasterAssassinCheckBox         matlab.ui.control.CheckBox
        ChallengesuniquetospecificrolesLabel  matlab.ui.control.Label
        MasterMageCheckBox             matlab.ui.control.CheckBox
        MasterFighterCheckBox          matlab.ui.control.CheckBox
        RoleLabel                      matlab.ui.control.Label
        Role                           matlab.ui.control.Label
        AllRandomAllChampionsCheckBox  matlab.ui.control.CheckBox
        HoldThatPoseCheckBox           matlab.ui.control.CheckBox
        InvincibleCheckBox             matlab.ui.control.CheckBox
        SamePentaDifferentChampCheckBox  matlab.ui.control.CheckBox
        PerfectionistCheckBox          matlab.ui.control.CheckBox
        ProteanOverrideCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        champList % List of champions as strings
        classObjects % Cell array of class objects
        challengeList % List of challenge properties in champ class objects
        challengeFullNames % full names of the challenges
    end
    
    methods (Access = private)
        
        function checkBoxChangedHelper(app,callbackName,propertyName)
            id = strcmp(app.champList,app.ChampionNameDropDown.Value);
            app.classObjects{id}.(propertyName) = app.(callbackName).Value;
            classObjs = app.classObjects;
            save("challengeData.mat","classObjs");
        end
        
        % Initialize classes
        function initClasses(app)
            app.classObjects = cell(size(app.champList));
            for i = 1:numel(app.champList)
                champ = app.champList(i);
                eval(champ + "Obj = " + champ + ";")
                app.classObjects{i} = eval(champ + "Obj;");
            end
        end
        
        function createChampList(app)
            app.champList = ["Aatrox"; "Ahri"; "Akali"; "Akshan";
                 "Alistar"; "Ambessa"; "Amumu"; "Anivia"; "Annie";
                 "Aphelios"; "Ashe"; "AurelionSol"; "Aurora"; "Azir";
                 "Bard"; "BelVeth"; "Blitzcrank"; "Brand"; "Braum";
                 "Briar"; "Caitlyn"; "Camille"; "Cassiopeia"; "ChoGath";
                 "Corki"; "Darius"; "Diana"; "Draven"; "DrMundo"; "Ekko";
                 "Elise"; "Evelynn"; "Ezreal"; "Fiddlesticks"; "Fiora";
                 "Fizz"; "Galio"; "Gangplank"; "Garen"; "Gnar"; "Gragas";
                 "Graves"; "Gwen"; "Hecarim"; "Heimerdinger"; "Hwei";
                 "Illaoi"; "Irelia"; "Ivern"; "Janna"; "JarvanIV"; "Jax";
                 "Jayce"; "Jhin"; "Jinx"; "KSante"; "KaiSa"; "Kalista";
                 "Karma"; "Karthus"; "Kassadin"; "Katarina"; "Kayle";
                 "Kayn"; "Kennen"; "KhaZix"; "Kindred"; "Kled"; "KogMaw";
                 "LeBlanc"; "LeeSin"; "Leona"; "Lillia"; "Lissandra";
                 "Lucian"; "Lulu"; "Lux"; "Malphite"; "Malzahar";
                 "Maokai"; "MasterYi"; "Mel"; "Milio"; "MissFortune";
                 "Mordekaiser"; "Morgana"; "Naafiri"; "Nami"; "Nasus";
                 "Nautilus"; "Neeko"; "Nidalee"; "Nilah"; "Nocturne";
                 "Nunu"; "Olaf"; "Orianna"; "Ornn"; "Pantheon"; "Poppy";
                 "Pyke"; "Qiyana"; "Quinn"; "Rakan"; "Rammus"; "RekSai";
                 "Rell"; "RenataGlasc"; "Renekton"; "Rengar"; "Riven";
                 "Rumble"; "Ryze"; "Samira"; "Sejuani"; "Senna";
                 "Seraphine"; "Sett"; "Shaco"; "Shen"; "Shyvana";
                 "Singed"; "Sion"; "Sivir"; "Skarner"; "Smolder"; "Sona";
                 "Soraka"; "Swain"; "Sylas"; "Syndra"; "TahmKench";
                 "Taliyah"; "Talon"; "Taric"; "Teemo"; "Thresh";
                 "Tristana"; "Trundle"; "Tryndamere"; "TwistedFate";
                 "Twitch"; "Udyr"; "Urgot"; "Varus"; "Vayne"; "Veigar";
                 "VelKoz"; "Vex"; "Vi"; "Viego"; "Viktor"; "Vladimir";
                 "Volibear"; "Warwick"; "Wukong"; "Xayah"; "Xerath";
                 "XinZhao"; "Yasuo"; "Yone"; "Yorick"; "Yuumi"; "Zac";
                 "Zed"; "Zeri"; "Ziggs"; "Zilean"; "Zoe"; "Zyra";];
        end
        
        function showHideRoleChallenges(app,id)
            % Fighter
            if find(strcmp(app.classObjects{id}.role,"fighter"))
                app.MasterFighterCheckBox.Visible = true;
                app.MasterFighterCheckBox.Value = app.classObjects{id}.masterFighter;
                app.FighterSpecialistCheckBox.Visible = true;
                app.FighterSpecialistCheckBox.Value = app.classObjects{id}.fighterSpecialist;
            else
                app.MasterFighterCheckBox.Visible = false;
                app.FighterSpecialistCheckBox.Visible = false;
            end

            % Assassin
            if find(strcmp(app.classObjects{id}.role,"assassin"))
                app.MasterAssassinCheckBox.Visible = true;
                app.MasterAssassinCheckBox.Value = app.classObjects{id}.masterAssassin;
                app.AssassinSpecialistCheckBox.Visible = true;
                app.AssassinSpecialistCheckBox.Value = app.classObjects{id}.assassinSpecialist;
            else
                app.MasterAssassinCheckBox.Visible = false;
                app.AssassinSpecialistCheckBox.Visible = false;
            end

            % Marksman
            if find(strcmp(app.classObjects{id}.role,"marksman"))
                app.MasterMarksmanCheckBox.Visible = true;
                app.MasterMarksmanCheckBox.Value = app.classObjects{id}.masterMarksman;
                app.MarksmanSpecialistCheckBox.Visible = true;
                app.MarksmanSpecialistCheckBox.Value = app.classObjects{id}.marksmanSpecialist;
            else
                app.MasterMarksmanCheckBox.Visible = false;
                app.MarksmanSpecialistCheckBox.Visible = false;
            end

            % Tank
            if find(strcmp(app.classObjects{id}.role,"tank"))
                app.MasterTankCheckBox.Visible = true;
                app.MasterTankCheckBox.Value = app.classObjects{id}.masterTank;
                app.TankSpecialistCheckBox.Visible = true;
                app.TankSpecialistCheckBox.Value = app.classObjects{id}.tankSpecialist;
            else
                app.MasterTankCheckBox.Visible = false;
                app.TankSpecialistCheckBox.Visible = false;
            end

            % Mage
            if find(strcmp(app.classObjects{id}.role,"mage"))
                app.MasterMageCheckBox.Visible = true;
                app.MasterMageCheckBox.Value = app.classObjects{id}.masterMage;
                app.MageSpecialistCheckBox.Visible = true;
                app.MageSpecialistCheckBox.Value = app.classObjects{id}.mageSpecialist;
            else
                app.MasterMageCheckBox.Visible = false;
                app.MageSpecialistCheckBox.Visible = false;
            end

            % Support
            if find(strcmp(app.classObjects{id}.role,"support"))
                app.MasterSupportCheckBox.Visible = true;
                app.MasterSupportCheckBox.Value = app.classObjects{id}.masterSupport;
                app.SupportSpecialistCheckBox.Visible = true;
                app.SupportSpecialistCheckBox.Value = app.classObjects{id}.supportSpecialist;
            else
                app.MasterSupportCheckBox.Visible = false;
                app.SupportSpecialistCheckBox.Visible = false;
            end
        end
        
        function createChallengeList(app)
            app.challengeList = ["protean"
                                 "perfectionist"
                                 "penta"
                                 "invincible"
                                 "allRandomAllChampions"];
            app.challengeFullNames = ["Protean Override"
                                      "Perfectionist"
                                      "Same Penta, Different Champ"
                                      "Invincible"
                                      "All Random All Champions"];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("challenges","challenges/Champion","challenges/Role")
            warning('off','MATLAB:load:variableNotFound');
            createChallengeList(app);
            createChampList(app)
            if ~exist("challengeData.mat","file")
                % If the mat file does not exist, create it as an empty
                % file. It will be filled in as user selects champions in
                % the app.
                initClasses(app);
                classObjs = app.classObjects;
                save("challengeData.mat","classObjs");
            else
                load("challengeData.mat","classObjs");
                app.classObjects = classObjs;
            end
            % Calling this method to populate the values of the check
            % boxes.
            ChampionNameDropDownValueChanged(app);
        end

        % Value changed function: ChampionNameDropDown
        function ChampionNameDropDownValueChanged(app, event)
            % id is the index location of the chosen champion in the array
            id = strcmp(app.champList,app.ChampionNameDropDown.Value);
            
            % Populate values in the app
            app.Role.Text = app.classObjects{id}.role;
            app.ProteanOverrideCheckBox.Value = app.classObjects{id}.protean;
            app.PerfectionistCheckBox.Value = app.classObjects{id}.perfectionist;
            app.SamePentaDifferentChampCheckBox.Value = app.classObjects{id}.penta;
            app.InvincibleCheckBox.Value = app.classObjects{id}.invincible;
            app.AllRandomAllChampionsCheckBox.Value = app.classObjects{id}.allRandomAllChampions;
            
            % Call helper function to show or hide role challenges when a
            % different champion is selected.
            showHideRoleChallenges(app,id)
            
            % Hold that pose is relevant only for some champions. Need
            % logic to show or hide this check box as needed?
            %app.HoldThatPoseCheckBox.Value = eval(objName + ".holdPose");

        end

        % Value changed function: ProteanOverrideCheckBox
        function ProteanOverrideCheckBoxValueChanged(app, event)
            cbName = "ProteanOverrideCheckBox";
            propName = "protean";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % turn warning back on
            warning('on','MATLAB:load:variableNotFound')
            % clean up path changes
            rmpath("challenges","challenges/Champion","challenges/Role")
        end

        % Value changed function: PerfectionistCheckBox
        function PerfectionistCheckBoxValueChanged(app, event)
            cbName = "PerfectionistCheckBox";
            propName = "perfectionist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SamePentaDifferentChampCheckBox
        function SamePentaDifferentChampCheckBoxValueChanged(app, event)
            cbName = "SamePentaDifferentChampCheckBox";
            propName = "penta";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: InvincibleCheckBox
        function InvincibleCheckBoxValueChanged(app, event)
            cbName = "InvincibleCheckBox";
            propName = "invincible";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: AllRandomAllChampionsCheckBox
        function AllRandomAllChampionsCheckBoxValueChanged(app, event)
            cbName = "AllRandomAllChampionsCheckBox";
            propName = "allRandomAllChampions";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterFighterCheckBox
        function MasterFighterCheckBoxValueChanged(app, event)
            cbName = "MasterFighterCheckBox";
            propName = "masterFighter";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterMageCheckBox
        function MasterMageCheckBoxValueChanged(app, event)
            cbName = "MasterMageCheckBox";
            propName = "masterMage";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterAssassinCheckBox
        function MasterAssassinCheckBoxValueChanged(app, event)
            cbName = "MasterAssassinCheckBox";
            propName = "masterAssassin";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterTankCheckBox
        function MasterTankCheckBoxValueChanged(app, event)
            cbName = "MasterTankCheckBox";
            propName = "masterTank";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterSupportCheckBox
        function MasterSupportCheckBoxValueChanged(app, event)
            cbName = "MasterSupportCheckBox";
            propName = "masterSupport";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: FighterSpecialistCheckBox
        function FighterSpecialistCheckBoxValueChanged(app, event)
            cbName = "FighterSpecialistCheckBox";
            propName = "fighterSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MageSpecialistCheckBox
        function MageSpecialistCheckBoxValueChanged(app, event)
            cbName = "MageSpecialistCheckBox";
            propName = "mageSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: AssassinSpecialistCheckBox
        function AssassinSpecialistCheckBoxValueChanged(app, event)
            cbName = "AssassinSpecialistCheckBox";
            propName = "assassinSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: TankSpecialistCheckBox
        function TankSpecialistCheckBoxValueChanged(app, event)
            cbName = "TankSpecialistCheckBox";
            propName = "tankSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SupportSpecialistCheckBox
        function SupportSpecialistCheckBoxValueChanged(app, event)
            cbName = "SupportSpecialistCheckBox";
            propName = "supportSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterMarksmanCheckBox
        function MasterMarksmanCheckBoxValueChanged(app, event)
            cbName = "MasterMarksmanCheckBox";
            propName = "masterMarksman";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MarksmanSpecialistCheckBox
        function MarksmanSpecialistCheckBoxValueChanged(app, event)
            cbName = "MarksmanSpecialistCheckBox";
            propName = "marksmanSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: HoldThatPoseCheckBox
        function HoldThatPoseCheckBoxValueChanged(app, event)
            cbName = "HoldThatPoseCheckBox";
            propName = "holdPose";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % When the button is pushed, generate a list of champions that
            % are eligible for the selected challenge but do not have
            % progress.
            id = str2double(app.ChallengeDropDown.Value);
            challenge = app.challengeList(id);
            app.UITable.Visible = true;
            checkProperties = zeros(length(app.champList),1);
            for i = 1:numel(app.champList)
                checkProperties(i) = isprop(app.classObjects{i},challenge);
                if app.classObjects{i}.(challenge)
                    % Only show champions that are missing progress
                    checkProperties(i) = false;
                end
                if isempty(app.PositionFilterDropDown.Value) || ...
                        app.PositionFilterDropDown.Value == 1
                    % Show all champions if nothing is specified
                elseif ~strcmp(app.classObjects{i}.lane,...
                        app.PositionFilterDropDown.Items(...
                        str2double(app.PositionFilterDropDown.Value)))
                    % If a champion's lane does NOT match the specified
                    % value, don't show it
                    checkProperties(i) = false;
                end
            end
            app.UITable.Data = app.champList(logical(checkProperties));
            app.UITable.ColumnName = "Need " + ...
                app.ChallengeDropDown.Items{str2double(app.ChallengeDropDown.Value)};
        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            % displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            % 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProteanOverrideCheckBox = uicheckbox(app.UIFigure);
            app.ProteanOverrideCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProteanOverrideCheckBoxValueChanged, true);
            app.ProteanOverrideCheckBox.Tooltip = {'Win Co-op vs. AI games with different champions'};
            app.ProteanOverrideCheckBox.Text = 'Protean Override';
            app.PerfectionistCheckBox = uicheckbox(app.UIFigure);
            app.PerfectionistCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerfectionistCheckBoxValueChanged, true);
            app.PerfectionistCheckBox.Text = 'Perfectionist (S+ grades)';
            app.SamePentaDifferentChampCheckBox = uicheckbox(app.UIFigure);
            app.SamePentaDifferentChampCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamePentaDifferentChampCheckBoxValueChanged, true);
            app.SamePentaDifferentChampCheckBox.Text = 'Same Penta, Different Champ';
            app.InvincibleCheckBox = uicheckbox(app.UIFigure);
            app.InvincibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvincibleCheckBoxValueChanged, true);
            app.InvincibleCheckBox.Text = 'Invincible (win without dying)';
            app.HoldThatPoseCheckBox = uicheckbox(app.UIFigure);
            app.HoldThatPoseCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldThatPoseCheckBoxValueChanged, true);
            app.HoldThatPoseCheckBox.Text = 'Hold That Pose (win 5-stack w/ 3 champs that have 2+ immobilizing spells)';
            app.AllRandomAllChampionsCheckBox = uicheckbox(app.UIFigure);
            app.AllRandomAllChampionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllRandomAllChampionsCheckBoxValueChanged, true);
            app.AllRandomAllChampionsCheckBox.Text = 'All Random All Champions (S- on different champions in ARAM)';
            app.Role = uilabel(app.UIFigure);
            app.Role.Text = '';
            app.RoleLabel = uilabel(app.UIFigure);
            app.RoleLabel.Text = 'Role:';
            app.MasterFighterCheckBox = uicheckbox(app.UIFigure);
            app.MasterFighterCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterFighterCheckBoxValueChanged, true);
            app.MasterFighterCheckBox.Visible = 'off';
            app.MasterFighterCheckBox.Text = 'Master Fighter';
            app.MasterMageCheckBox = uicheckbox(app.UIFigure);
            app.MasterMageCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterMageCheckBoxValueChanged, true);
            app.MasterMageCheckBox.Visible = 'off';
            app.MasterMageCheckBox.Text = 'Master Mage';
            app.ChallengesuniquetospecificrolesLabel = uilabel(app.UIFigure);
            app.ChallengesuniquetospecificrolesLabel.Text = 'Challenges unique to specific roles:';
            app.MasterAssassinCheckBox = uicheckbox(app.UIFigure);
            app.MasterAssassinCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterAssassinCheckBoxValueChanged, true);
            app.MasterAssassinCheckBox.Visible = 'off';
            app.MasterAssassinCheckBox.Text = 'Master Assassin';
            app.MasterTankCheckBox = uicheckbox(app.UIFigure);
            app.MasterTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterTankCheckBoxValueChanged, true);
            app.MasterTankCheckBox.Visible = 'off';
            app.MasterTankCheckBox.Text = 'Master Tank';
            app.MasterSupportCheckBox = uicheckbox(app.UIFigure);
            app.MasterSupportCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterSupportCheckBoxValueChanged, true);
            app.MasterSupportCheckBox.Visible = 'off';
            app.MasterSupportCheckBox.Text = 'Master Support';
            app.FighterSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.FighterSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @FighterSpecialistCheckBoxValueChanged, true);
            app.FighterSpecialistCheckBox.Visible = 'off';
            app.FighterSpecialistCheckBox.Text = 'Fighter Specialist';
            app.MageSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.MageSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @MageSpecialistCheckBoxValueChanged, true);
            app.MageSpecialistCheckBox.Visible = 'off';
            app.MageSpecialistCheckBox.Text = 'Mage Specialist';
            app.AssassinSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.AssassinSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @AssassinSpecialistCheckBoxValueChanged, true);
            app.AssassinSpecialistCheckBox.Visible = 'off';
            app.AssassinSpecialistCheckBox.Text = 'Assassin Specialist';
            app.TankSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.TankSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @TankSpecialistCheckBoxValueChanged, true);
            app.TankSpecialistCheckBox.Visible = 'off';
            app.TankSpecialistCheckBox.Text = 'Tank Specialist';
            app.SupportSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.SupportSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @SupportSpecialistCheckBoxValueChanged, true);
            app.SupportSpecialistCheckBox.Visible = 'off';
            app.SupportSpecialistCheckBox.Text = 'Support Specialist';
            app.MasterMarksmanCheckBox = uicheckbox(app.UIFigure);
            app.MasterMarksmanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterMarksmanCheckBoxValueChanged, true);
            app.MasterMarksmanCheckBox.Visible = 'off';
            app.MasterMarksmanCheckBox.Text = 'Master Marksman';
            app.MarksmanSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.MarksmanSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarksmanSpecialistCheckBoxValueChanged, true);
            app.MarksmanSpecialistCheckBox.Visible = 'off';
            app.MarksmanSpecialistCheckBox.Text = 'Marksman Specialist';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'List Champions';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Champions'};
            app.UITable.RowName = {};
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.UITable.Visible = 'off';
            app.ChampionDropDownLabel = uilabel(app.UIFigure);
            app.ChampionDropDownLabel.Text = 'Champion';
            app.ChampionNameDropDown = uidropdown(app.UIFigure);
            app.ChampionNameDropDown.Items = {'Aatrox', 'Ahri', 'Akali', 'Akshan', 'Alistar', 'Ambessa', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Aurora', 'Azir', 'Bard', 'BelVeth', 'Blitzcrank', 'Brand', 'Braum', 'Briar', 'Caitlyn', 'Camille', 'Cassiopeia', 'ChoGath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Gwen', 'Hecarim', 'Heimerdinger', 'Hwei', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'KSante', 'KaiSa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'KhaZix', 'Kindred', 'Kled', 'KogMaw', 'LeBlanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'Mel', 'Milio', 'MissFortune', 'Mordekaiser', 'Morgana', 'Naafiri', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nilah', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'RenataGlasc', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Smolder', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'VelKoz', 'Vex', 'Vi', 'Viego', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'Wukong', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Zeri', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionNameDropDown.Editable = 'on';
            app.ChampionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChampionNameDropDownValueChanged, true);
            app.ChampionNameDropDown.Placeholder = 'Aatrox';
            app.ChampionNameDropDown.Value = 'Ahri';
            app.ChallengeDropDownLabel = uilabel(app.UIFigure);
            app.ChallengeDropDownLabel.Text = 'Challenge';
            app.ChallengeDropDown = uidropdown(app.UIFigure);
            app.ChallengeDropDown.Items = {'Protean Override', 'Perfectionist', 'Same Penta, Different Champ', 'Invincible', 'All Random, All Champions'};
            app.ChallengeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ChallengeDropDown.Value = '1';
            app.PositionFilterDropDownLabel = uilabel(app.UIFigure);
            app.PositionFilterDropDownLabel.Text = 'Position Filter';
            app.PositionFilterDropDown = uidropdown(app.UIFigure);
            app.PositionFilterDropDown.Items = {'', 'top', 'mid', 'jungle', 'bot', 'support'};
            app.PositionFilterDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.PositionFilterDropDown.Editable = 'on';
            app.PositionFilterDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = challengeTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end